classdef isinim_hesaplama < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        MetehanNASAYLabel   matlab.ui.control.Label
        BerkeKOCADERELabel  matlab.ui.control.Label
        Image               matlab.ui.control.Image
        EimField            matlab.ui.control.NumericEditField
        BoylamField         matlab.ui.control.NumericEditField
        AzimutField         matlab.ui.control.NumericEditField
        RakmField           matlab.ui.control.NumericEditField
        EnlemField          matlab.ui.control.NumericEditField
        ExitButton          matlab.ui.control.Button
        Export              matlab.ui.control.Button
        AzimutSlider        matlab.ui.control.Slider
        AzimutSliderLabel   matlab.ui.control.Label
        EimSlider           matlab.ui.control.Slider
        EimSliderLabel      matlab.ui.control.Label
        RakmSlider          matlab.ui.control.Slider
        RakmSliderLabel     matlab.ui.control.Label
        BoylamSlider        matlab.ui.control.Slider
        BoylamSliderLabel   matlab.ui.control.Label
        EnlemSlider         matlab.ui.control.Slider
        EnlemSliderLabel    matlab.ui.control.Label
        graph3d_3           matlab.ui.control.UIAxes
        graph3d_2           matlab.ui.control.UIAxes
        graph3d             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Fi % Enlem 
        Psi % Boylam
        Rakim % Rakim
        Beta % Eğim
        Azimuth % Azimuth açısı
        w % Saat açısı
        n % Gün sayısı
        Ibeam
        Idiff
        Itotal
        BetaSun
        AzimuthSun
        Rb
        Ru=0.18
        costeta
        costetaz
        Td
        Tb 
        Sigma
        Ion
        Monthly 
    end
    
    methods (Access = private)
        
        function Sun(app)
            app.w = -120:1:120;
            app.n = 1:1:365;
            app.Sigma = 23.45.*sind(360.*(284+app.n)./365); % deklinasyon değerleri
            app.Ion = 1367*(1+0.0033.*cosd(360.*meshgrid(app.n,[-120:1:120])/365)); %% Gunes sabiti yillik degisimi
            [app.Sigma,app.w] = meshgrid(app.Sigma,app.w);
            app.BetaSun = asind(cosd(app.Fi).*cosd(app.Sigma).*cosd(app.w)+sind(app.Fi).*sind(app.Sigma)); % Betasun hesaplama
            app.BetaSun(app.BetaSun<0)=0;%<0 olanları sıfırla 
            app.AzimuthSun = asind(cosd(app.Sigma).*sind(app.w)./cosd(app.BetaSun)); % AzimuthSun hesaplama
            % Azimuth açısı >90°de işaret hatası vermektedir. 
            Temp0 = app.AzimuthSun;
            Temp1 = 180-app.AzimuthSun;
            Temp2 = -180-app.AzimuthSun;
            Temp0(cosd(app.w)<(tand(app.Sigma)./tand(app.Fi))) = 0; % |>90°| olanları sıfırlayalım
            Temp1(app.w<0 | cosd(app.w)>=(tand(app.Sigma)./tand(app.Fi))) = 0;% |<90°| veya w<0 olanları sıfırla
            Temp2(app.w>0 | cosd(app.w)>=(tand(app.Sigma)./tand(app.Fi))) = 0;% |<90°| veya w>0 olanları sıfırla
            app.AzimuthSun = Temp0 + Temp1 + Temp2; % Azimut hesaplandı
            
        end
  
        function Sun_angle(app)
            % Atmosfer direkt ışınım geçirgenligi 
            A = sind(app.Fi).*cosd(app.Beta); 
            B = cosd(app.Fi).*sind(app.Beta).*cosd(app.Azimuth); 
            C = sind(app.Beta).*sind(app.Azimuth); 
            D = cosd(app.Fi).*cosd(app.Beta); 
            E = sind(app.Fi).*sind(app.Beta).*cosd(app.Azimuth); 
            app.costeta = (A-B).*sind(app.Sigma)+(C.*sind(app.w)+(D+E).*cosd(app.w)).*cosd(app.Sigma);  % Gunes gelis acisi 
            app.costetaz = sind(app.Fi).*sind(app.Sigma)+cosd(app.Fi).*cosd(app.w).*cosd(app.Sigma); 
            app.costetaz(app.costetaz<0)=1e-20; 
            app.Rb=app.costeta./app.costetaz;
        end
        
        function Gecirgenlik(app)
            a0_=0.4237-0.00821*(6-app.Rakim).^2;
            a1_=0.5055+0.00595*(6.5-app.Rakim).^2;
            k_=0.2711+0.01858*(2.5-app.Rakim).^2;
            % Yaz ayları için r0=0.97, r1=0.99, rk=1.02
            a0=ones(size(app.BetaSun)); a0(:,91:273)=0.97*a0_; %n=91-273 arası yaz olsun
            a1=ones(size(app.BetaSun)); a1(:,91:273)=0.99*a1_; 
            k=ones(size(app.BetaSun)); k(:,91:273)=1.02*k_;
            % Kış ayları için r0=1.03, r1=1.01, rk=1.00
            a0(:,1:90)=1.03*a0_; a0(:,274:365)=1.03*a0_;
            a1(:,1:90)=1.01*a1_; a1(:,274:365)=1.01*a1_;
            k(:,1:90)=1.00*k_; k(:,274:365)=1.00*k_;
            app.Tb = a0+a1.*exp(-k./app.costetaz);
            app.Td = 0.2710-0.2939.*app.Tb;
        end
        
        function Isinim(app)
            app.Ibeam = app.Ion.*app.costetaz.*app.Tb;
            app.Idiff = app.Ion.*app.costetaz.*app.Td;
            %Açılı ışınım geçirgenliği
            app.Ru = 0.18;
            app.Itotal = (app.Ibeam.*app.Rb)+... 
            (app.Idiff.*((1+cosd(app.Beta))/2))+((app.Ibeam+app.Idiff).*((1-cosd(app.Beta))/2).*app.Ru);
            app.Itotal(app.Itotal<0) = 0;
        end
        
        function plotgraph(app)
            mesh(app.graph3d,app.n,app.w/15,app.Itotal); 
            xlabel(app.graph3d,'Gün sayısı');
            ylabel(app.graph3d,'Saat');
            zlabel(app.graph3d,'Toplam Işınım şiddeti [W/m^2]');
            mesh(app.graph3d_2,app.n,app.w/15,app.Ibeam); 
            xlabel(app.graph3d_2,'Gün sayısı');
            ylabel(app.graph3d_2,'Saat');
            zlabel(app.graph3d_2,'Direkt Işınım şiddeti [W/m^2]');
            mesh(app.graph3d_3,app.n,app.w/15,app.Idiff); 
            xlabel(app.graph3d_3,'Gün sayısı');
            ylabel(app.graph3d_3,'Saat');
            zlabel(app.graph3d_3,'Difüz Işınım şiddeti [W/m^2]');
        end
        
        function graph(app)
            Sun(app)
            Sun_angle(app)
            Gecirgenlik(app)
            Isinim(app)
            plotgraph(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%% Baslangic degerleri %%%% 
            app.Fi = 38.46; % Enlem 
            app.Psi = 30; % Boylam
            app.Rakim = 0.1; % Rakim
            app.Beta = 30;% Eğim
            app.Azimuth = 0; % Azimuth açısı
            %%%%%%%%%%
            graph(app) 
        end

        % Value changing function: EnlemSlider
        function EnlemSliderValueChanging(app, event)
            changingValue = event.Value;            
            app.Fi = changingValue;
            app.EnlemField.Value = app.EnlemSlider.Value; 
            graph(app)
        end

        % Value changing function: EimSlider
        function EimSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Beta = changingValue; 
            app.EimField.Value = app.EimSlider.Value; 
            graph(app) 
        end

        % Value changing function: RakmSlider
        function RakmSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Rakim = changingValue; 
            app.RakmField.Value = app.RakmSlider.Value; 
            graph(app)
        end

        % Value changing function: BoylamSlider
        function BoylamSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Psi = changingValue; 
            app.BoylamField.Value = app.BoylamSlider.Value; 
            graph(app)
        end

        % Value changing function: AzimutSlider
        function AzimutSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Azimuth = changingValue; 
            app.AzimutField.Value = app.AzimutSlider.Value; 
            graph(app)
        end

        % Button pushed function: Export
        function ExportPushed(app, event)
            exportapp(app.UIFigure,'Graphics.jpg')
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.UIFigure)
        end

        % Value changed function: EnlemField
        function EnlemFieldValueChanged(app, event)
            value = app.EnlemField.Value;
            app.Fi = value; 
            app.EnlemSlider.Value = app.EnlemField.Value; 
            graph(app)
        end

        % Value changed function: EimField
        function EimFieldValueChanged(app, event)
            value = app.EimField.Value;
            app.Beta = value; 
            app.EimSlider.Value = app.EimField.Value; 
            graph(app)
        end

        % Value changed function: BoylamField
        function BoylamFieldValueChanged(app, event)
            value = app.BoylamField.Value;
            app.Psi = value; 
            app.BoylamSlider.Value = app.BoylamField.Value; 
            graph(app)
        end

        % Value changed function: AzimutField
        function AzimutFieldValueChanged(app, event)
            value = app.AzimutField.Value;
            app.Azimuth = value; 
            app.AzimutSlider.Value = app.AzimutField.Value; 
            graph(app)
        end

        % Value changed function: RakmField
        function RakmFieldValueChanged(app, event)
            value = app.RakmField.Value;
            app.Rakim = value; 
            app.RakmSlider.Value = app.RakmField.Value; 
            graph(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.graph3d = uiaxes(app.UIFigure);
            title(app.graph3d, 'Toplam Işınım Grafiği')
            xlabel(app.graph3d, 'X')
            ylabel(app.graph3d, 'Y')
            zlabel(app.graph3d, 'Z')
            app.graph3d_2 = uiaxes(app.UIFigure);
            title(app.graph3d_2, 'Direkt Işınım Grafiği ')
            xlabel(app.graph3d_2, 'X')
            ylabel(app.graph3d_2, 'Y')
            zlabel(app.graph3d_2, 'Z')
            app.graph3d_3 = uiaxes(app.UIFigure);
            title(app.graph3d_3, 'Difüz Işınım Grafiği ')
            xlabel(app.graph3d_3, 'X')
            ylabel(app.graph3d_3, 'Y')
            zlabel(app.graph3d_3, 'Z')
            app.EnlemSliderLabel = uilabel(app.UIFigure);
            app.EnlemSliderLabel.Text = 'Enlem';
            app.EnlemSlider = uislider(app.UIFigure);
            app.EnlemSlider.Limits = [36 42];
            app.EnlemSlider.ValueChangingFcn = createCallbackFcn(app, @EnlemSliderValueChanging, true);
            app.EnlemSlider.Value = 36;
            app.BoylamSliderLabel = uilabel(app.UIFigure);
            app.BoylamSliderLabel.Text = 'Boylam';
            app.BoylamSlider = uislider(app.UIFigure);
            app.BoylamSlider.Limits = [26 45];
            app.BoylamSlider.ValueChangingFcn = createCallbackFcn(app, @BoylamSliderValueChanging, true);
            app.BoylamSlider.Value = 26;
            app.RakmSliderLabel = uilabel(app.UIFigure);
            app.RakmSliderLabel.Text = 'Rakım';
            app.RakmSlider = uislider(app.UIFigure);
            app.RakmSlider.Limits = [0 2.5];
            app.RakmSlider.ValueChangingFcn = createCallbackFcn(app, @RakmSliderValueChanging, true);
            app.EimSliderLabel = uilabel(app.UIFigure);
            app.EimSliderLabel.Text = 'Eğim';
            app.EimSlider = uislider(app.UIFigure);
            app.EimSlider.Limits = [0 90];
            app.EimSlider.ValueChangingFcn = createCallbackFcn(app, @EimSliderValueChanging, true);
            app.AzimutSliderLabel = uilabel(app.UIFigure);
            app.AzimutSliderLabel.Text = 'Azimut';
            app.AzimutSlider = uislider(app.UIFigure);
            app.AzimutSlider.Limits = [-180 180];
            app.AzimutSlider.ValueChangingFcn = createCallbackFcn(app, @AzimutSliderValueChanging, true);
            app.Export = uibutton(app.UIFigure, 'push');
            app.Export.ButtonPushedFcn = createCallbackFcn(app, @ExportPushed, true);
            app.Export.Text = 'Export';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.EnlemField = uieditfield(app.UIFigure, 'numeric');
            app.EnlemField.ValueChangedFcn = createCallbackFcn(app, @EnlemFieldValueChanged, true);
            app.RakmField = uieditfield(app.UIFigure, 'numeric');
            app.RakmField.ValueChangedFcn = createCallbackFcn(app, @RakmFieldValueChanged, true);
            app.AzimutField = uieditfield(app.UIFigure, 'numeric');
            app.AzimutField.ValueChangedFcn = createCallbackFcn(app, @AzimutFieldValueChanged, true);
            app.BoylamField = uieditfield(app.UIFigure, 'numeric');
            app.BoylamField.ValueChangedFcn = createCallbackFcn(app, @BoylamFieldValueChanged, true);
            app.EimField = uieditfield(app.UIFigure, 'numeric');
            app.EimField.ValueChangedFcn = createCallbackFcn(app, @EimFieldValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_Ege_Uni.png';
            app.BerkeKOCADERELabel = uilabel(app.UIFigure);
            app.BerkeKOCADERELabel.Text = 'Berke KOCADERE';
            app.MetehanNASAYLabel = uilabel(app.UIFigure);
            app.MetehanNASAYLabel.Text = 'Metehan NASAY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = isinim_hesaplama

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end